classdef autoplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure              % Autoplot
        UIAxes         matlab.ui.control.UIAxes      % Acceleration vs Time
        Panel          matlab.ui.container.Panel     % Teams
        Team1Button    matlab.ui.control.StateButton % Team 1
        Team2Button    matlab.ui.control.StateButton % Team 2
        Team3Button    matlab.ui.control.StateButton % Team 3
        Team4Button    matlab.ui.control.StateButton % Team 4
        Team5Button    matlab.ui.control.StateButton % Team 5
        Clear1Button   matlab.ui.control.Button     
        Clear2Button   matlab.ui.control.Button     
        Clear3Button   matlab.ui.control.Button     
        Clear4Button   matlab.ui.control.Button     
        Clear5Button   matlab.ui.control.Button     
        LabelDropDown  matlab.ui.control.Label       % Filter:
        FilterDropDown matlab.ui.control.DropDown    % None, Moving Window, Savi...
        SaveButton     matlab.ui.control.Button      % Save
        ClearButton    matlab.ui.control.Button      % Clear
    end

    
    properties (Access = private)
        Measure        % Defines when the app should be measuring sensor data
        Device         % Mobile object
        cleanupObj     % Description
        AccelPlots     % Cell of data plots, filtered and unfiltered
        LastFilter     % Index of last filter
        Saved          % Boolean to tell whether the current data has been saved
        TeamButtons    % Vector of all the team buttons
        AllComponents  % Vector of all GUI components
        ClearButtons   % Vector of all the clear team buttons
    end
    
    methods (Access = private)
        
        function results = record(app, start, button)
            if start
                discardlogs(app.Device);
                app.Device.Logging = 1;
            else
                label = button.Text;
                team = str2double(label(end));
                app.Device.Logging = 0;
                [acceleration, time] = accellog(app.Device);
                y_acceleration = acceleration(:, 2);
                
                p = plot(app.UIAxes, time, y_acceleration, 'Visible', 'off');
                app.AccelPlots{team, 1} = p;

                pmw = plot(app.UIAxes, time, ...
                           smooth(y_acceleration, 3), ...
                           'Visible', 'off', ...
                           'Color', p.Color);
                app.AccelPlots{team, 2} = pmw;

                if length(y_acceleration) > 100
                    psg = plot(app.UIAxes, time, ...
                               sgolayfilt(y_acceleration, 10, 41), ...
                               'Visible', 'off', ...
                               'Color', p.Color);
                    app.AccelPlots{team, 3} = psg;
                end
                
                app.AccelPlots{team, 4} = true;
                displayPlot(app, team, app.FilterDropDown.Value, button);
            end
        end
        
        function results = cleanup(app)
            connector off;
        end
        
        function results = recordButtonPressed(app, button, clearButton)
            value = button.Value;
            label = button.Text;
            team = str2double(label(end));

            if app.AccelPlots{team, 1} ~= 0
                if app.AccelPlots{team, 4} == false
                    app.AccelPlots{team, 4} = true;
                    displayPlot(app, team, app.FilterDropDown.Value, button);
                else
                    app.AccelPlots{team, 4} = false;
                    p = app.AccelPlots{team, app.FilterDropDown.Value};
                    p.Visible = 'off';
                end
            else
                record(app, value, button);

                otherComponents = setdiff(app.AllComponents, button);
                for b = otherComponents
                    if app.Device.Logging == 0
                        b.Enable = 'on';
                    else
                        b.Enable = 'off';
                    end
                end

                button.Value = 1;
            end
            
            if app.Device.Logging == 0
                clearButton.Enable = 'on';
            end
        end

        function results = displayPlot(app, team, toDisplay, button)
            toNotDisplay = setdiff(1 : 3, toDisplay);
            pd = app.AccelPlots{team, toDisplay};
            pd.Visible = 'on';
            button.FontColor = pd.Color;
            
            for i = toNotDisplay
                pnd = app.AccelPlots{team, i};
                if ~isnumeric(pnd)
                    pnd.Visible = 'off';
                end
            end
        end

        function results = createVariable(app, name, data)
            results = sprintf('%s = [%s];', name, num2str(data));
        end

        function results = saveable(app)
            results = ~all(all(cellfun(@isempty, app.AccelPlots)));
        end

        function results = recapture(app, button)
            team = str2double(button.Text(end));
            msg = sprintf('Delete and recapture data for team %d?', team);
            choice = questdlg(msg, 'Confirm Recapture', 'Yes', 'No', 'No');
            if strcmp(choice, 'Yes')
                for i = 1:3
                    p = app.AccelPlots{team, i};
                    p.Visible = 'off';
                end

                app.AccelPlots(team, :) = cell(1, 4);
                recordButtonPressed(app, button);
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.Device;
            app.cleanupObj = onCleanup(@() cleanup(app));
            app.AccelPlots = cell(5, 4);
            app.FilterDropDown.ItemsData = 1 : 3;
            app.LastFilter = 1;
            app.Saved = false;
            app.TeamButtons = [app.Team1Button, app.Team2Button, ...
                               app.Team3Button, app.Team4Button, ...
                               app.Team5Button];
            app.ClearButtons = [app.Clear1Button, app.Clear2Button, ...
                                app.Clear3Button, app.Clear4Button, ...
                                app.Clear5Button];
            app.AllComponents = [app.TeamButtons, app.FilterDropDown, ...
                                 app.SaveButton, app.ClearButton];
            
            diary 'ip.txt';
            connector on birdbrain;
            diary off;
            ipAddress = regexp(fileread('ip.txt'), ...
                '\d+.\d+.\d+.\d+', 'match', 'once');
            delete('ip.txt');

            msg = sprintf(['Computer IP address is %s, ' ...
                           'password is "birdbrain".\n' ...
                           'Press OK when phone is connected'], ipAddress);
            app.Device = mobiledev;
            
            while app.Device.Connected == 0
                m = msgbox(msg);
                waitfor(m);
            end
            
            app.Device.AccelerationSensorEnabled = 1;
            app.Device.SampleRate = 'high';
            
            m = msgbox('Ready to record.');
            waitfor(m);
            hold(app.UIAxes, 'on');
        end

        % Team1Button value changed function
        function Team1ButtonValueChanged(app)
            recordButtonPressed(app, app.Team1Button, app.Clear1Button);
        end

        % Team2Button value changed function
        function Team2ButtonValueChanged(app)
            recordButtonPressed(app, app.Team2Button, app.Clear2Button);
        end

        % Team3Button value changed function
        function Team3ButtonValueChanged(app)
            recordButtonPressed(app, app.Team3Button, app.Clear3Button);
        end

        % Team4Button value changed function
        function Team4ButtonValueChanged(app)
            recordButtonPressed(app, app.Team4Button, app.Clear4Button);
        end

        % Team5Button value changed function
        function Team5ButtonValueChanged(app)
            recordButtonPressed(app, app.Team5Button, app.Clear5Button);
        end

        % FilterDropDown value changed function
        function onFilterChanged(app, event)
            value = app.FilterDropDown.Value;
            for team = 1 : 5
                if app.AccelPlots{team, 4}
                    if value == 1
                        displayPlot(app, team, 1);
                    elseif value == 2
                        displayPlot(app, team, 2);
                    else
                        p = app.AccelPlots{team, 1};
                        if length(p.XData) < 50
                            msg = sprintf(['Cannot use Savitzky-Golay filter. ', ...
                                           'Need over 50 data points, have %d.'], ...
                                           length(p.XData));
                            uialert(app.UIFigure, msg, 'Invalid Filter');
                            app.FilterDropDown.Value = app.LastFilter;
                            value = app.LastFilter;
                            displayPlot(app, team, value);
                        else
                            displayPlot(app, team, 3);
                        end
                    end
                end
            end
            
            app.LastFilter = value;
        end

        % SaveButton button pushed function
        function OnSave(app)
            if ~saveable(app)
                errordlg('No data to save.');
                return
            end

            for team = 1 : 5
                if ~isempty(app.AccelPlots{team, 1})
                    prefix = sprintf('team%d_', team);

                    p = app.AccelPlots{team, 1};
                    eval(createVariable(app, [prefix, 'time'], p.XData));
                    eval(createVariable(app, [prefix, 'accel_none'], p.YData));
                    
                    pmw = app.AccelPlots{team, 2};
                    eval(createVariable(app, [prefix, 'accel_mw'], pmw.YData));
                    
                    if ~isempty(app.AccelPlots{team, 3})
                        psg = app.AccelPlots{team, 3};
                        eval(createVariable(app, [prefix, 'accel_sg'], psg.YData));
                    end
                end
            end

            file = uiputfile('group#.mat', 'Save Group Data');
            if file ~= 0
                save(file, 'team*');
                app.Saved = true;
            end
        end

        % ClearButton button pushed function
        function OnClear(app)
            if ~app.Saved && saveable(app)
                decision = questdlg(['Do you wish to save group ', ...
                                     'data before clearing?'], 'Save Data');
                if strcmp(decision, 'Yes')
                    OnSave(app);
                    
                    while ~app.Saved && strcmp(decision, 'Yes')
                        decision = questdlg('Data not saved, continue?', ...
                                            'Save Data', 'Yes', 'No', 'Yes');
                        if strcmp(decision, 'Yes')
                            OnSave(app);
                        end
                    end
                elseif strcmp(decision, 'Cancel')
                    return
                end
            end

            cla(app.UIAxes);
            app.AccelPlots = cell(5, 4);
            app.LastFilter = 1;
            for button = app.TeamButtons
                button.Value = 0;
                button.FontColor = 'black';
            end
            for button = app.ClearButtons
                button.Enable = 'off';
            end
        end

        % Clear1Button button pushed function
        function Clear1ButtonButtonPushed(app)
            recapture(app, app.Team1Button);
        end

        % Clear2Button button pushed function
        function Clear2ButtonButtonPushed(app)
            recapture(app, app.Team2Button);
        end

        % Clear3Button button pushed function
        function Clear3ButtonButtonPushed(app)
            recapture(app, app.Team3Button);
        end

        % Clear4Button button pushed function
        function Clear4ButtonButtonPushed(app)
            recapture(app, app.Team4Button);
        end

        % Clear5Button button pushed function
        function Clear5ButtonButtonPushed(app)
            recapture(app, app.Team5Button);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Autoplot';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acceleration vs Time');
            xlabel(app.UIAxes, 'Time [s]');
            ylabel(app.UIAxes, 'Acceleration [ms^-2]');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Teams';
            app.Panel.Units = 'pixels';
            app.Team1Button = uibutton(app.Panel, 'state');
            app.Team1Button.ValueChangedFcn = createCallbackFcn(app, @Team1ButtonValueChanged);
            app.Team1Button.Text = 'Team 1';
            app.Team2Button = uibutton(app.Panel, 'state');
            app.Team2Button.ValueChangedFcn = createCallbackFcn(app, @Team2ButtonValueChanged);
            app.Team2Button.Text = 'Team 2';
            app.Team3Button = uibutton(app.Panel, 'state');
            app.Team3Button.ValueChangedFcn = createCallbackFcn(app, @Team3ButtonValueChanged);
            app.Team3Button.Text = 'Team 3';
            app.Team4Button = uibutton(app.Panel, 'state');
            app.Team4Button.ValueChangedFcn = createCallbackFcn(app, @Team4ButtonValueChanged);
            app.Team4Button.Text = 'Team 4';
            app.Team5Button = uibutton(app.Panel, 'state');
            app.Team5Button.ValueChangedFcn = createCallbackFcn(app, @Team5ButtonValueChanged);
            app.Team5Button.Text = 'Team 5';
            app.Clear1Button = uibutton(app.Panel, 'push');
            app.Clear1Button.ButtonPushedFcn = createCallbackFcn(app, @Clear1ButtonButtonPushed);
            app.Clear1Button.Enable = 'off';
            app.Clear1Button.Icon = 'refresh-page-option.png';
            app.Clear1Button.Text = '';
            app.Clear2Button = uibutton(app.Panel, 'push');
            app.Clear2Button.ButtonPushedFcn = createCallbackFcn(app, @Clear2ButtonButtonPushed);
            app.Clear2Button.Enable = 'off';
            app.Clear2Button.Icon = 'refresh-page-option.png';
            app.Clear2Button.Text = '';
            app.Clear3Button = uibutton(app.Panel, 'push');
            app.Clear3Button.ButtonPushedFcn = createCallbackFcn(app, @Clear3ButtonButtonPushed);
            app.Clear3Button.Enable = 'off';
            app.Clear3Button.Icon = 'refresh-page-option.png';
            app.Clear3Button.Text = '';
            app.Clear4Button = uibutton(app.Panel, 'push');
            app.Clear4Button.ButtonPushedFcn = createCallbackFcn(app, @Clear4ButtonButtonPushed);
            app.Clear4Button.Enable = 'off';
            app.Clear4Button.Icon = 'refresh-page-option.png';
            app.Clear4Button.Text = '';
            app.Clear5Button = uibutton(app.Panel, 'push');
            app.Clear5Button.ButtonPushedFcn = createCallbackFcn(app, @Clear5ButtonButtonPushed);
            app.Clear5Button.Enable = 'off';
            app.Clear5Button.Icon = 'refresh-page-option.png';
            app.Clear5Button.Text = '';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Filter:';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'Moving Window', 'Savitzky-Golay'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @onFilterChanged, true);
            app.FilterDropDown.Value = 'None';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @OnSave);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @OnClear);
            app.ClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = autoplot()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end